classdef BloodFlow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        LeftGrid             matlab.ui.container.GridLayout
        TituloLabel          matlab.ui.control.Label
        EstadoInicialPanel   matlab.ui.container.Panel
        EstadoInicialGrid    matlab.ui.container.GridLayout
        ALLabel              matlab.ui.control.Label
        ALField              matlab.ui.control.NumericEditField
        ARLabel              matlab.ui.control.Label
        ARField              matlab.ui.control.NumericEditField
        ULLabel              matlab.ui.control.Label
        ULField              matlab.ui.control.NumericEditField
        URLabel              matlab.ui.control.Label
        URField              matlab.ui.control.NumericEditField
        ArteriaPanel         matlab.ui.container.Panel
        ArteriaGrid          matlab.ui.container.GridLayout
        ELabel               matlab.ui.control.Label
        EField               matlab.ui.control.NumericEditField
        HLabel               matlab.ui.control.Label
        HField               matlab.ui.control.NumericEditField
        R0Label              matlab.ui.control.Label
        R0Field              matlab.ui.control.NumericEditField
        DiscretizacionPanel  matlab.ui.container.Panel
        DiscretizacionGrid   matlab.ui.container.GridLayout
        NCelasLabel          matlab.ui.control.Label
        NCelasField          matlab.ui.control.NumericEditField
        CFLLabel             matlab.ui.control.Label
        CFLField             matlab.ui.control.NumericEditField
        TEndLabel            matlab.ui.control.Label
        TEndField            matlab.ui.control.NumericEditField
        VisualizacionPanel   matlab.ui.container.Panel
        VisualizacionGrid    matlab.ui.container.GridLayout
        VistaLabel           matlab.ui.control.Label
        VistaSwitch          matlab.ui.control.Switch
        ExactaCheckBox       matlab.ui.control.CheckBox
        VelocidadeLabel      matlab.ui.control.Label
        VelocidadeSlider     matlab.ui.control.Slider
        Botons1Grid          matlab.ui.container.GridLayout
        ExecutarButton       matlab.ui.control.Button
        PausaButton          matlab.ui.control.Button
        Botons2Grid          matlab.ui.container.GridLayout
        DeterButton          matlab.ui.control.Button
        PecharButton         matlab.ui.control.Button
        EstadoLabel          matlab.ui.control.Label
        ErroLabel            matlab.ui.control.Label
        RightPanel           matlab.ui.container.Panel
        RightGrid            matlab.ui.container.GridLayout
        TempoLabel           matlab.ui.control.Label
        Panel2D              matlab.ui.container.Panel
        Grid2D               matlab.ui.container.GridLayout
        AxesA                matlab.ui.control.UIAxes
        AxesD                matlab.ui.control.UIAxes
        AxesU                matlab.ui.control.UIAxes
        AxesXT               matlab.ui.control.UIAxes
        Panel3D              matlab.ui.container.Panel
        Grid3D               matlab.ui.container.GridLayout
        Axes3D               matlab.ui.control.UIAxes
        TempoSliderLabel     matlab.ui.control.Label
        TempoSlider          matlab.ui.control.Slider
    end


    properties (Access = private)
        IsRunning = false      % a simulation is running
        IsPaused = false       % the running simulation is paused
        StopRequested = false  % the user asked to stop the simulation
        Setup = []             % plot data that do not change in time
        Hist = []              % stored solution, one column per time step
        NFrames = 0            % number of stored time steps
        CurrentFrame = 0       % time step shown in the plots
        Plots = struct()       % handles of the plotted objects
        Lims = struct()        % current y-limits of the 2D plots
        Unstable = false       % the numerical solution is no longer valid
        UnstableTime = NaN     % time at which the solution became unstable
    end

    methods (Access = public)

        function stopped = newFrame(app, setup, time, aplot, uplot, aexact, uexact)
            % Store and plot one time step computed by blood_flow_compute_app.
            % Returns true when the simulation must stop.
            if ~isvalid(app)
                stopped = true;
                return
            end
            if ~isempty(setup)
                app.Setup = setup;
                N = numel(aplot);
                app.Hist = struct('t', zeros(1,0), 'A', zeros(N,0), 'u', zeros(N,0), ...
                    'Aex', zeros(N,0), 'uex', zeros(N,0));
                app.NFrames = 0;
                app.Lims = struct();
                app.TempoSlider.Limits = [0 setup.tEnd];
                app.TempoSlider.Value = 0;
                app.initPlots();
            end
            n = app.NFrames + 1;
            app.NFrames = n;
            app.Hist.t(n) = time;
            app.Hist.A(:,n) = aplot;
            app.Hist.u(:,n) = uplot;
            app.Hist.Aex(:,n) = aexact;
            app.Hist.uex(:,n) = uexact;
            if ~app.Unstable && (any(~isfinite(aplot)) || any(real(aplot) <= 0) || ...
                    any(~isfinite(uplot)) || any(imag(aplot) ~= 0) || any(imag(uplot) ~= 0))
                % The numerical solution is no longer physical: warn, but keep going
                app.Unstable = true;
                app.UnstableTime = time;
                app.EstadoLabel.Text = sprintf(['Atención: a solución numérica fíxose inestable ' ...
                    'en t = %.4f s. É o CFL demasiado grande?'], time);
                app.EstadoLabel.FontColor = [0.8 0 0];
            end
            app.drawFrame(n);
            if isfinite(time)
                app.TempoSlider.Value = min(max(real(time), 0), app.TempoSlider.Limits(2));
            end
            drawnow
            pause(app.frameDelay());
            while isvalid(app) && app.IsPaused && ~app.StopRequested
                pause(0.1);
            end
            stopped = ~isvalid(app) || app.StopRequested;
        end
    end

    methods (Access = private)

        function initPlots(app)
            % Create the plot objects for a new simulation
            s = app.Setup;
            z = nan(size(s.xplot));
            ze = nan(size(s.xexact));
            blue = [0 0.447 0.741];

            cla(app.AxesA)
            hold(app.AxesA, 'on')
            app.Plots.A = plot(app.AxesA, s.xplot, z, 'o', 'Color', blue);
            app.Plots.Aex = plot(app.AxesA, s.xexact, ze, 'r-', 'LineWidth', 1.5);
            hold(app.AxesA, 'off')
            legend(app.AxesA, [app.Plots.A app.Plots.Aex], {'Numérica', 'Exacta'}, ...
                'Location', 'northeast', 'AutoUpdate', 'off');

            cla(app.AxesD)
            hold(app.AxesD, 'on')
            app.Plots.Dup = plot(app.AxesD, s.xplot, z, 'o', 'Color', blue);
            app.Plots.Dlo = plot(app.AxesD, s.xplot, z, 'o', 'Color', blue);
            app.Plots.Dexup = plot(app.AxesD, s.xexact, ze, 'r-', 'LineWidth', 1.5);
            app.Plots.Dexlo = plot(app.AxesD, s.xexact, ze, 'r-', 'LineWidth', 1.5);
            hold(app.AxesD, 'off')

            cla(app.AxesU)
            hold(app.AxesU, 'on')
            app.Plots.U = plot(app.AxesU, s.xplot, z, 'o', 'Color', blue);
            app.Plots.Uex = plot(app.AxesU, s.xexact, ze, 'r-', 'LineWidth', 1.5);
            hold(app.AxesU, 'off')

            cla(app.AxesXT)
            hold(app.AxesXT, 'on')
            plot(app.AxesXT, s.charX, s.charT);
            app.Plots.TimeLine = plot(app.AxesXT, [0 1], [0 0], 'k-', 'LineWidth', 1.5);
            hold(app.AxesXT, 'off')
            axis(app.AxesXT, [0, 1, 0, s.tEnd])
            xlim([app.AxesA app.AxesD app.AxesU], [0 1])

            % 3D vessel: surface of revolution plus velocity cones
            cla(app.Axes3D)
            [X, Y, Z] = cylinder(ones(1, numel(s.xplot)), 100);
            app.Plots.Surf = surf(app.Axes3D, X, Z, Y);
            colormap(app.Axes3D, 'copper')
            shading(app.Axes3D, 'interp')
            light(app.Axes3D, 'Position', [20, 2, -20]);
            set(app.Plots.Surf, 'FaceColor', 'y', 'BackFaceLighting', 'lit', 'FaceAlpha', 0.6, ...
                'FaceLighting', 'gouraud', 'AmbientStrength', 0.4, 'DiffuseStrength', 0.6, ...
                'SpecularStrength', 0.5, 'SpecularExponent', 30)
            axis(app.Axes3D, [-s.lmax, s.lmax, 0, 1, -s.lmax, s.lmax])
            view(app.Axes3D, [-60, 20])
            app.Axes3D.CameraViewAngleMode = 'manual';
            app.Plots.Cones = gobjects(0);

            app.updateExactVisibility();
        end

        function drawFrame(app, n)
            % Show stored time step n in the current view
            if n < 1 || n > app.NFrames
                return
            end
            app.CurrentFrame = n;
            % Real parts only: an unstable solution may become complex
            t = real(app.Hist.t(n));
            A = real(app.Hist.A(:,n));
            u = real(app.Hist.u(:,n));
            Aex = real(app.Hist.Aex(:,n));
            uex = real(app.Hist.uex(:,n));
            app.TempoLabel.Text = sprintf('Tempo: %.4f s', t);
            r = sqrt(max(A, 0)/pi);
            if strcmp(app.VistaSwitch.Value, '2D')
                rex = sqrt(max(Aex, 0)/pi);
                set(app.Plots.A, 'YData', A)
                set(app.Plots.Aex, 'YData', Aex)
                set(app.Plots.Dup, 'YData', r)
                set(app.Plots.Dlo, 'YData', -r)
                set(app.Plots.Dexup, 'YData', rex)
                set(app.Plots.Dexlo, 'YData', -rex)
                set(app.Plots.U, 'YData', u)
                set(app.Plots.Uex, 'YData', uex)
                set(app.Plots.TimeLine, 'YData', [t t])
                app.expandLimits(app.AxesA, 'A', [A; Aex], false);
                app.expandLimits(app.AxesD, 'D', [r; rex], true);
                app.expandLimits(app.AxesU, 'U', [u; uex], false);
            else
                try
                    app.draw3D(r', u);
                catch
                    % Non-finite values cannot be drawn in 3D: keep the last picture
                end
            end
        end

        function draw3D(app, r, v)
            % 3D animation of blood_flow_compute.m: vessel wall and velocity cones
            [X, Y, Z] = cylinder(r, 100);
            set(app.Plots.Surf, 'XData', X, 'YData', Z, 'ZData', Y)
            delete(app.Plots.Cones)
            amp = [0.74, 0.55, 0.35, 0.];
            n_theta_plot = 12;
            r_gap = max(1, floor(numel(r)/10));
            V = repmat([v(1), v(2:r_gap:end-1)', v(end)], 1, n_theta_plot+1);
            X1 = cos(repmat(linspace(0, 2*pi, n_theta_plot+1)', 1, numel([r(1), r(2:r_gap:end-1), r(end)])));
            Y1 = sin(repmat(linspace(0, 2*pi, n_theta_plot+1)', 1, numel([r(1), r(2:r_gap:end-1), r(end)])));
            R = repmat([r(1), r(2:r_gap:end-1), r(end)]', 1, n_theta_plot+1);
            Z = repmat(linspace(0, 1, numel([r(1), r(2:r_gap:end-1), r(end)]))', 1, n_theta_plot+1);
            hold(app.Axes3D, 'on')
            cones = gobjects(1, numel(amp));
            for j = 1:numel(amp)
                cones(j) = coneplot(app.Axes3D, amp(j)*R.*X1', Z, amp(j)*R.*Y1', ...
                    0*X1, V, 0*Y1, 0.1, 'r', 'nointerp');
            end
            hold(app.Axes3D, 'off')
            set(cones, 'FaceColor', 'r', 'EdgeColor', 'none')
            app.Plots.Cones = cones;
        end

        function expandLimits(app, ax, key, vals, symmetric)
            % Grow (never shrink) the y-limits of a 2D plot during a simulation
            vals = real(vals(isfinite(vals)));
            if isempty(vals)
                return
            end
            lo = min(vals);
            hi = max(vals);
            if symmetric
                hi = max(abs([lo hi]));
                lo = -hi;
            end
            if isfield(app.Lims, key)
                lo = min(lo, app.Lims.(key)(1));
                hi = max(hi, app.Lims.(key)(2));
            end
            app.Lims.(key) = [lo hi];
            pad = 0.1*(hi - lo);
            if pad == 0
                pad = max(0.1*abs(hi), 0.1);
            end
            ax.YLim = [lo - pad, hi + pad];
        end

        function updateExactVisibility(app)
            % Show or hide the exact solution
            if ~isfield(app.Plots, 'Aex')
                return
            end
            vis = matlab.lang.OnOffSwitchState(app.ExactaCheckBox.Value);
            set([app.Plots.Aex app.Plots.Dexup app.Plots.Dexlo app.Plots.Uex], 'Visible', vis)
        end

        function applyView(app)
            % Switch between the 2D plots and the 3D animation
            is2D = strcmp(app.VistaSwitch.Value, '2D');
            app.Panel2D.Visible = is2D;
            app.Panel3D.Visible = ~is2D;
        end

        function d = frameDelay(app)
            % Pause between frames from the animation speed slider (1 slow, 10 fast)
            d = 0.3*(10 - app.VelocidadeSlider.Value)/9;
        end

        function setRunning(app, running)
            % Enable or disable the controls while a simulation runs
            inputs = [app.ALField app.ARField app.ULField app.URField app.EField ...
                app.HField app.R0Field app.NCelasField app.CFLField app.TEndField];
            set(inputs, 'Enable', ~running)
            app.ExecutarButton.Enable = ~running;
            app.PausaButton.Enable = running;
            app.DeterButton.Enable = running;
            app.PausaButton.Text = 'Pausa';
            app.TempoSlider.Enable = ~running && app.NFrames > 1;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Make sure the numerical code next to the app is used
            appFolder = fileparts(which(class(app)));
            if ~isempty(appFolder)
                addpath(appFolder);
            end
            app.applyView();
            app.setRunning(false);
        end

        % Button pushed function: ExecutarButton
        function ExecutarButtonPushed(app, event)
            if app.IsRunning
                return
            end
            aL = app.ALField.Value;
            aR = app.ARField.Value;
            uL = app.ULField.Value;
            uR = app.URField.Value;
            E = app.EField.Value;
            h = app.HField.Value;
            r0 = app.R0Field.Value;
            NCELLS = app.NCelasField.Value;
            CFL = app.CFLField.Value;
            tEnd = app.TEndField.Value;

            app.IsRunning = true;
            app.IsPaused = false;
            app.StopRequested = false;
            app.Unstable = false;
            app.UnstableTime = NaN;
            app.NFrames = 0;
            app.setRunning(true);
            app.ErroLabel.Text = '';
            app.EstadoLabel.FontColor = [0 0 0];
            app.EstadoLabel.Text = 'Calculando...';
            drawnow

            try
                res = blood_flow_compute_app(aL, aR, uL, uR, NCELLS, CFL, tEnd, E, h, r0, app);
            catch err
                if ~isvalid(app)
                    return
                end
                app.IsRunning = false;
                app.IsPaused = false;
                app.setRunning(false);
                if app.Unstable
                    % The unstable solution has blown up and the method cannot go on
                    tLast = real(app.Hist.t(app.NFrames));
                    app.EstadoLabel.Text = sprintf(['A solución fíxose inestable en t = %.4f s e o ' ...
                        'método non puido continuar despois de t = %.4f s.'], app.UnstableTime, tLast);
                    uialert(app.UIFigure, sprintf(['A solución numérica fíxose inestable en t = %.4f s ' ...
                        'e explotou: o método non pode continuar despois de t = %.4f s.\n\n' ...
                        'Move o control de tempo para ver como se estropeou. ' ...
                        'Proba despois cun número CFL menor.'], app.UnstableTime, tLast), ...
                        'Solución inestable', 'Icon', 'warning');
                else
                    app.EstadoLabel.Text = 'A simulación fallou.';
                    uialert(app.UIFigure, ['Non se puido completar a simulación: ' err.message], 'Erro');
                end
                return
            end
            if ~isvalid(app)
                return
            end
            app.IsRunning = false;
            app.IsPaused = false;
            app.setRunning(false);
            if app.Unstable
                if res.completed
                    fin = 'Simulación completada';
                else
                    fin = sprintf('Simulación detida en t = %.4f s', real(res.time));
                end
                app.EstadoLabel.Text = sprintf(['%s, pero a solución fíxose inestable en ' ...
                    't = %.4f s. Proba cun número CFL menor.'], fin, app.UnstableTime);
                app.ErroLabel.Text = sprintf(['Erro no tempo final (t = %.4f s)\n' ...
                    'L1:  A = %.3e   Au = %.3e\nL∞:  A = %.3e   Au = %.3e'], ...
                    real(res.time), res.l1error(1), res.l1error(2), res.linferror(1), res.linferror(2));
            elseif res.completed
                app.EstadoLabel.Text = 'Simulación completada.';
                app.ErroLabel.Text = sprintf(['Erro no tempo final (t = %.4f s)\n' ...
                    'L1:  A = %.3e   Au = %.3e\nL∞:  A = %.3e   Au = %.3e'], ...
                    res.time, res.l1error(1), res.l1error(2), res.linferror(1), res.linferror(2));
            else
                app.EstadoLabel.Text = sprintf('Simulación detida en t = %.4f s.', res.time);
                app.ErroLabel.Text = sprintf(['Erro en t = %.4f s\n' ...
                    'L1:  A = %.3e   Au = %.3e\nL∞:  A = %.3e   Au = %.3e'], ...
                    res.time, res.l1error(1), res.l1error(2), res.linferror(1), res.linferror(2));
            end
        end

        % Button pushed function: PausaButton
        function PausaButtonPushed(app, event)
            if ~app.IsRunning
                return
            end
            app.IsPaused = ~app.IsPaused;
            if app.IsPaused
                app.PausaButton.Text = 'Continuar';
                app.TempoSlider.Enable = app.NFrames > 1;
                app.EstadoLabel.Text = 'En pausa. Podes mover o control de tempo para ver instantes anteriores.';
            else
                app.PausaButton.Text = 'Pausa';
                app.TempoSlider.Enable = false;
                if app.Unstable
                    app.EstadoLabel.Text = sprintf(['Atención: a solución numérica fíxose inestable ' ...
                        'en t = %.4f s. É o CFL demasiado grande?'], app.UnstableTime);
                else
                    app.EstadoLabel.Text = 'Calculando...';
                end
            end
        end

        % Button pushed function: DeterButton
        function DeterButtonPushed(app, event)
            app.StopRequested = true;
            app.IsPaused = false;
        end

        % Button pushed function: PecharButton
        function PecharButtonPushed(app, event)
            app.StopRequested = true;
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.StopRequested = true;
            delete(app)
        end

        % Value changed function: VistaSwitch
        function VistaSwitchValueChanged(app, event)
            app.applyView();
            if app.CurrentFrame > 0
                app.drawFrame(app.CurrentFrame);
            end
        end

        % Value changed function: ExactaCheckBox
        function ExactaCheckBoxValueChanged(app, event)
            app.updateExactVisibility();
        end

        % Value changing function: TempoSlider
        function TempoSliderValueChanging(app, event)
            if app.NFrames > 0
                [~, n] = min(abs(real(app.Hist.t(1:app.NFrames)) - event.Value));
                app.drawFrame(n);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Fluxo sanguíneo nunha arteria';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.RightPanel = uipanel(app.GridLayout);
            app.RightGrid = uigridlayout(app.RightPanel);
            app.TempoSlider = uislider(app.RightGrid);
            app.TempoSlider.Limits = [0 0.014];
            app.TempoSlider.ValueChangingFcn = createCallbackFcn(app, @TempoSliderValueChanging, true);
            app.TempoSlider.Enable = 'off';
            app.TempoSlider.Tooltip = 'Despois de executar (ou en pausa), move este control para ver calquera instante';
            app.TempoSliderLabel = uilabel(app.RightGrid);
            app.TempoSliderLabel.Text = 'Tempo [s]';
            app.Panel3D = uipanel(app.RightGrid);
            app.Panel3D.Visible = 'off';
            app.Grid3D = uigridlayout(app.Panel3D);
            app.Axes3D = uiaxes(app.Grid3D);
            app.Panel2D = uipanel(app.RightGrid);
            app.Grid2D = uigridlayout(app.Panel2D);
            app.AxesXT = uiaxes(app.Grid2D);
            title(app.AxesXT, 'Ondas no plano x-t')
            xlabel(app.AxesXT, 'x/L')
            ylabel(app.AxesXT, 't [s]')
            app.AxesU = uiaxes(app.Grid2D);
            ylabel(app.AxesU, 'u [m/s]')
            app.AxesD = uiaxes(app.Grid2D);
            ylabel(app.AxesD, 'D - diámetro do vaso')
            app.AxesA = uiaxes(app.Grid2D);
            ylabel(app.AxesA, 'A/A_0')
            app.TempoLabel = uilabel(app.RightGrid);
            app.TempoLabel.Text = 'Tempo: 0.0000 s';
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftGrid = uigridlayout(app.LeftPanel);
            app.ErroLabel = uilabel(app.LeftGrid);
            app.ErroLabel.Text = '';
            app.EstadoLabel = uilabel(app.LeftGrid);
            app.EstadoLabel.Text = 'Introduce os parámetros e preme Executar.';
            app.Botons2Grid = uigridlayout(app.LeftGrid);
            app.PecharButton = uibutton(app.Botons2Grid, 'push');
            app.PecharButton.ButtonPushedFcn = createCallbackFcn(app, @PecharButtonPushed, true);
            app.PecharButton.Text = 'Pechar';
            app.DeterButton = uibutton(app.Botons2Grid, 'push');
            app.DeterButton.ButtonPushedFcn = createCallbackFcn(app, @DeterButtonPushed, true);
            app.DeterButton.Enable = 'off';
            app.DeterButton.Text = 'Deter';
            app.Botons1Grid = uigridlayout(app.LeftGrid);
            app.PausaButton = uibutton(app.Botons1Grid, 'push');
            app.PausaButton.ButtonPushedFcn = createCallbackFcn(app, @PausaButtonPushed, true);
            app.PausaButton.Enable = 'off';
            app.PausaButton.Text = 'Pausa';
            app.ExecutarButton = uibutton(app.Botons1Grid, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Text = 'Executar';
            app.VisualizacionPanel = uipanel(app.LeftGrid);
            app.VisualizacionPanel.Title = 'Visualización';
            app.VisualizacionGrid = uigridlayout(app.VisualizacionPanel);
            app.VelocidadeSlider = uislider(app.VisualizacionGrid);
            app.VelocidadeSlider.Limits = [1 10];
            app.VelocidadeSlider.MajorTicks = [1 10];
            app.VelocidadeSlider.MajorTickLabels = {'lenta', 'rápida'};
            app.VelocidadeSlider.Tooltip = 'Velocidade da animación';
            app.VelocidadeSlider.Value = 7;
            app.VelocidadeLabel = uilabel(app.VisualizacionGrid);
            app.VelocidadeLabel.Text = 'Velocidade da animación';
            app.ExactaCheckBox = uicheckbox(app.VisualizacionGrid);
            app.ExactaCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExactaCheckBoxValueChanged, true);
            app.ExactaCheckBox.Tooltip = 'Amosa en vermello a solución exacta do problema de Riemann';
            app.ExactaCheckBox.Text = 'Amosar a solución exacta';
            app.ExactaCheckBox.Value = true;
            app.VistaSwitch = uiswitch(app.VisualizacionGrid, 'slider');
            app.VistaSwitch.Items = {'2D', '3D'};
            app.VistaSwitch.ValueChangedFcn = createCallbackFcn(app, @VistaSwitchValueChanged, true);
            app.VistaSwitch.Tooltip = 'Gráficas 2D ou animación 3D da arteria (pódese cambiar en calquera momento)';
            app.VistaSwitch.Value = '2D';
            app.VistaLabel = uilabel(app.VisualizacionGrid);
            app.VistaLabel.Text = 'Vista';
            app.DiscretizacionPanel = uipanel(app.LeftGrid);
            app.DiscretizacionPanel.Title = 'Discretización';
            app.DiscretizacionGrid = uigridlayout(app.DiscretizacionPanel);
            app.TEndField = uieditfield(app.DiscretizacionGrid, 'numeric');
            app.TEndField.LowerLimitInclusive = 'off';
            app.TEndField.Limits = [0 Inf];
            app.TEndField.ValueDisplayFormat = '%.4g';
            app.TEndField.Tooltip = 'Tempo final da simulación';
            app.TEndField.Value = 0.014;
            app.TEndLabel = uilabel(app.DiscretizacionGrid);
            app.TEndLabel.Tooltip = 'Tempo final da simulación';
            app.TEndLabel.Interpreter = 'tex';
            app.TEndLabel.Text = 'Tempo final [s]';
            app.CFLField = uieditfield(app.DiscretizacionGrid, 'numeric');
            app.CFLField.LowerLimitInclusive = 'off';
            app.CFLField.Limits = [0 Inf];
            app.CFLField.ValueDisplayFormat = '%.4g';
            app.CFLField.Tooltip = 'Número de Courant-Friedrichs-Lewy: controla o paso de tempo';
            app.CFLField.Value = 0.7;
            app.CFLLabel = uilabel(app.DiscretizacionGrid);
            app.CFLLabel.Tooltip = 'Número de Courant-Friedrichs-Lewy: controla o paso de tempo';
            app.CFLLabel.Interpreter = 'tex';
            app.CFLLabel.Text = 'Número CFL';
            app.NCelasField = uieditfield(app.DiscretizacionGrid, 'numeric');
            app.NCelasField.Limits = [2 Inf];
            app.NCelasField.RoundFractionalValues = 'on';
            app.NCelasField.ValueDisplayFormat = '%.0f';
            app.NCelasField.Tooltip = 'Número de celas no eixo lonxitudinal da arteria';
            app.NCelasField.Value = 80;
            app.NCelasLabel = uilabel(app.DiscretizacionGrid);
            app.NCelasLabel.Tooltip = 'Número de celas no eixo lonxitudinal da arteria';
            app.NCelasLabel.Interpreter = 'tex';
            app.NCelasLabel.Text = 'Número de celas';
            app.ArteriaPanel = uipanel(app.LeftGrid);
            app.ArteriaPanel.Title = 'Propiedades da arteria';
            app.ArteriaGrid = uigridlayout(app.ArteriaPanel);
            app.R0Field = uieditfield(app.ArteriaGrid, 'numeric');
            app.R0Field.LowerLimitInclusive = 'off';
            app.R0Field.Limits = [0 Inf];
            app.R0Field.ValueDisplayFormat = '%.4g';
            app.R0Field.Tooltip = 'Raio da arteria en repouso (A_0 = π r_0²)';
            app.R0Field.Value = 0.00999;
            app.R0Label = uilabel(app.ArteriaGrid);
            app.R0Label.Tooltip = 'Raio da arteria en repouso (A_0 = π r_0²)';
            app.R0Label.Interpreter = 'tex';
            app.R0Label.Text = 'Raio de referencia  r_0 [m]';
            app.HField = uieditfield(app.ArteriaGrid, 'numeric');
            app.HField.LowerLimitInclusive = 'off';
            app.HField.Limits = [0 Inf];
            app.HField.ValueDisplayFormat = '%.4g';
            app.HField.Tooltip = 'Espesor da parede da arteria';
            app.HField.Value = 0.0011;
            app.HLabel = uilabel(app.ArteriaGrid);
            app.HLabel.Tooltip = 'Espesor da parede da arteria';
            app.HLabel.Interpreter = 'tex';
            app.HLabel.Text = 'Espesor da parede  h [m]';
            app.EField = uieditfield(app.ArteriaGrid, 'numeric');
            app.EField.LowerLimitInclusive = 'off';
            app.EField.Limits = [0 Inf];
            app.EField.ValueDisplayFormat = '%.4g';
            app.EField.Tooltip = 'Rixidez da parede: canto maior, máis ríxida é a arteria';
            app.EField.Value = 400000;
            app.ELabel = uilabel(app.ArteriaGrid);
            app.ELabel.Tooltip = 'Rixidez da parede: canto maior, máis ríxida é a arteria';
            app.ELabel.Interpreter = 'tex';
            app.ELabel.Text = 'Módulo de Young  E [Pa]';
            app.EstadoInicialPanel = uipanel(app.LeftGrid);
            app.EstadoInicialPanel.Title = 'Estado inicial (problema de Riemann)';
            app.EstadoInicialGrid = uigridlayout(app.EstadoInicialPanel);
            app.URField = uieditfield(app.EstadoInicialGrid, 'numeric');
            app.URField.ValueDisplayFormat = '%.4g';
            app.URField.Tooltip = 'Velocidade do sangue á dereita da descontinuidade';
            app.URLabel = uilabel(app.EstadoInicialGrid);
            app.URLabel.Tooltip = 'Velocidade do sangue á dereita da descontinuidade';
            app.URLabel.Interpreter = 'tex';
            app.URLabel.Text = 'Velocidade á dereita  u_R [m/s]';
            app.ULField = uieditfield(app.EstadoInicialGrid, 'numeric');
            app.ULField.ValueDisplayFormat = '%.4g';
            app.ULField.Tooltip = 'Velocidade do sangue á esquerda da descontinuidade';
            app.ULLabel = uilabel(app.EstadoInicialGrid);
            app.ULLabel.Tooltip = 'Velocidade do sangue á esquerda da descontinuidade';
            app.ULLabel.Interpreter = 'tex';
            app.ULLabel.Text = 'Velocidade á esquerda  u_L [m/s]';
            app.ARField = uieditfield(app.EstadoInicialGrid, 'numeric');
            app.ARField.LowerLimitInclusive = 'off';
            app.ARField.Limits = [0 Inf];
            app.ARField.ValueDisplayFormat = '%.4g';
            app.ARField.Tooltip = 'Área da sección á dereita da descontinuidade, relativa á área de referencia A_0';
            app.ARField.Value = 1.1;
            app.ARLabel = uilabel(app.EstadoInicialGrid);
            app.ARLabel.Tooltip = 'Área da sección á dereita da descontinuidade, relativa á área de referencia A_0';
            app.ARLabel.Interpreter = 'tex';
            app.ARLabel.Text = 'Área á dereita  A_R/A_0';
            app.ALField = uieditfield(app.EstadoInicialGrid, 'numeric');
            app.ALField.LowerLimitInclusive = 'off';
            app.ALField.Limits = [0 Inf];
            app.ALField.ValueDisplayFormat = '%.4g';
            app.ALField.Tooltip = 'Área da sección á esquerda da descontinuidade, relativa á área de referencia A_0';
            app.ALField.Value = 2.2;
            app.ALLabel = uilabel(app.EstadoInicialGrid);
            app.ALLabel.Tooltip = 'Área da sección á esquerda da descontinuidade, relativa á área de referencia A_0';
            app.ALLabel.Interpreter = 'tex';
            app.ALLabel.Text = 'Área á esquerda  A_L/A_0';
            app.TituloLabel = uilabel(app.LeftGrid);
            app.TituloLabel.Text = 'Fluxo sanguíneo nunha arteria';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BloodFlow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end