classdef CNS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        LeftGrid                      matlab.ui.container.GridLayout
        HeatedliddrivencavityLabel    matlab.ui.control.Label
        ContornoPanel                 matlab.ui.container.Panel
        ContornoGrid                  matlab.ui.container.GridLayout
        TemperaturainicialEditFieldLabel  matlab.ui.control.Label
        TemperaturainicialEditField   matlab.ui.control.NumericEditField
        TemperaturafronteirainferiorEditFieldLabel  matlab.ui.control.Label
        TemperaturafronteirainferiorEditField  matlab.ui.control.NumericEditField
        PerfildetemperaturaDropDownLabel  matlab.ui.control.Label
        PerfildetemperaturaDropDown   matlab.ui.control.DropDown
        VelocidadefronteirasuperiorEditFieldLabel  matlab.ui.control.Label
        VelocidadefronteirasuperiorEditField  matlab.ui.control.NumericEditField
        RadiadorCheckBox              matlab.ui.control.CheckBox
        TemperaturaradiadorEditFieldLabel  matlab.ui.control.Label
        TemperaturaradiadorEditField  matlab.ui.control.NumericEditField
        FiestraCheckBox               matlab.ui.control.CheckBox
        TemperaturafiestraEditFieldLabel  matlab.ui.control.Label
        TemperaturafiestraEditField   matlab.ui.control.NumericEditField
        PropiedadesdofluidoPanel      matlab.ui.container.Panel
        FluidoGrid                    matlab.ui.container.GridLayout
        ViscosidadeEditFieldLabel     matlab.ui.control.Label
        ViscosidadeEditField          matlab.ui.control.NumericEditField
        CoefexpansintrmicaEditFieldLabel  matlab.ui.control.Label
        CoefexpansintrmicaEditField   matlab.ui.control.NumericEditField
        CoefconduccincalorEditFieldLabel  matlab.ui.control.Label
        CoefconduccincalorEditField   matlab.ui.control.NumericEditField
        GravidadeEditFieldLabel       matlab.ui.control.Label
        GravidadeEditField            matlab.ui.control.NumericEditField
        DiscretizacionespacialPanel   matlab.ui.container.Panel
        EspacialGrid                  matlab.ui.container.GridLayout
        DominioxLabel                 matlab.ui.control.Label
        DominioxEditField             matlab.ui.control.NumericEditField
        yEditFieldLabel               matlab.ui.control.Label
        yEditField                    matlab.ui.control.NumericEditField
        NumeroelementosNxEditFieldLabel  matlab.ui.control.Label
        NumeroelementosNxEditField    matlab.ui.control.NumericEditField
        NyEditFieldLabel              matlab.ui.control.Label
        NyEditField                   matlab.ui.control.NumericEditField
        DiscretizaciontemporalPanel   matlab.ui.container.Panel
        TemporalGrid                  matlab.ui.container.GridLayout
        TempofinalLabel               matlab.ui.control.Label
        TempofinalEditField           matlab.ui.control.NumericEditField
        CFLSliderLabel                matlab.ui.control.Label
        CFLSlider                     matlab.ui.control.Slider
        VisualizacionPanel            matlab.ui.container.Panel
        VisualizacionGrid             matlab.ui.container.GridLayout
        VelocidadeDropDownLabel       matlab.ui.control.Label
        VelocidadeDropDown            matlab.ui.control.DropDown
        VelocidadeAnimacionLabel      matlab.ui.control.Label
        VelocidadeAnimacionSlider     matlab.ui.control.Slider
        Botons1Grid                   matlab.ui.container.GridLayout
        RunButton                     matlab.ui.control.Button
        PausaButton                   matlab.ui.control.Button
        Botons2Grid                   matlab.ui.container.GridLayout
        DeterButton                   matlab.ui.control.Button
        PecharButton                  matlab.ui.control.Button
        EstadoLabel                   matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        RightGrid                     matlab.ui.container.GridLayout
        TempoLabel                    matlab.ui.control.Label
        TempoGrid                     matlab.ui.container.GridLayout
        TempoSliderLabel              matlab.ui.control.Label
        TempoSlider                   matlab.ui.control.Slider
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        AxesE                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        IsRunning = false      % a simulation is running
        IsPaused = false       % the running simulation is paused
        StopRequested = false  % the user asked to stop the simulation
        Setup = []             % plot data that do not change in time
        Hist = []              % stored time steps (t, T, ub, vb)
        NStored = 0            % number of stored time steps
        Stride = 1             % store one of every Stride time steps
        NSteps = 0             % number of computed time steps
        Last = []              % last computed time step
        Et = []                % time of every computed step
        Tm = []                % mean temperature of every computed step
        Plots = struct()       % handles of the plotted objects
    end

    properties (Access = private, Constant)
        MaxStored = 400        % maximum number of stored time steps
    end

    methods (Access = public)

        function stopped = newFrame(app, setup, time, T, ub, vb, Etot)
            % Store and plot one time step computed by NavierStokes_SIMPLE_app.
            % Returns true when the simulation must stop.
            if ~isvalid(app)
                stopped = true;
                return
            end
            if ~isempty(setup)
                app.Setup = setup;
                app.Hist = struct('t', zeros(1,0), 'T', zeros([size(T) 0]), ...
                    'ub', zeros([size(ub) 0]), 'vb', zeros([size(vb) 0]));
                app.NStored = 0;
                app.Stride = 1;
                app.NSteps = 0;
                app.Et = zeros(1,0);
                app.Tm = zeros(1,0);
                app.TempoSlider.Limits = [0 setup.tend];
                app.TempoSlider.Value = 0;
                app.initPlots();
            end
            app.NSteps = app.NSteps + 1;
            app.Et(end+1) = time;
            app.Tm(end+1) = mean(T(:)); % mean temperature in the domain
            app.Last = struct('t', time, 'T', T, 'ub', ub, 'vb', vb);
            if mod(app.NSteps - 1, app.Stride) == 0
                app.storeFrame(app.Last);
            end
            app.drawFrame(app.Last);
            app.TempoSlider.Value = min(time, app.TempoSlider.Limits(2));
            drawnow
            pause(app.frameDelay());
            while isvalid(app) && app.IsPaused && ~app.StopRequested
                pause(0.1);
            end
            stopped = ~isvalid(app) || app.StopRequested;
        end
    end

    methods (Access = private)

        function storeFrame(app, f)
            % Keep a time step for the time slider (thinned out if too many)
            if app.NStored > 0 && app.Hist.t(app.NStored) == f.t
                return
            end
            n = app.NStored + 1;
            app.Hist.t(n) = f.t;
            app.Hist.T(:,:,n) = f.T;
            app.Hist.ub(:,:,n) = f.ub;
            app.Hist.vb(:,:,n) = f.vb;
            app.NStored = n;
            if n >= app.MaxStored
                keep = 1:2:n;
                app.Hist.t = app.Hist.t(keep);
                app.Hist.T = app.Hist.T(:,:,keep);
                app.Hist.ub = app.Hist.ub(:,:,keep);
                app.Hist.vb = app.Hist.vb(:,:,keep);
                app.NStored = numel(keep);
                app.Stride = 2*app.Stride;
            end
        end

        function f = storedFrame(app, k)
            % Stored time step number k
            f = struct('t', app.Hist.t(k), 'T', app.Hist.T(:,:,k), ...
                'ub', app.Hist.ub(:,:,k), 'vb', app.Hist.vb(:,:,k));
        end

        function initPlots(app)
            % Prepare the axes for a new simulation
            s = app.Setup;
            axs = [app.UIAxes app.UIAxes_2];
            for ax = axs
                cla(ax)
                ax.NextPlot = 'replacechildren';
                ax.XLim = [s.xL s.xR];
                ax.YLim = [s.yL s.yR];
                ax.XTick = linspace(s.xL, s.xR, 11);
                ax.YTick = linspace(s.yL, s.yR, 11);
                ax.DataAspectRatio = [1 1 1];
            end
            colormap(app.UIAxes, jet)
            Tmax = eps;
            if ~strcmp(s.Tprofile, 'Adiabática')
                Tmax = max(Tmax, s.Tb);
            end
            if s.radOn
                Tmax = max(Tmax, s.Trad);
            end
            Tmin = min(0, s.T0);
            Tmax = max(Tmax, s.T0);
            if s.winOn
                Tmin = min(Tmin, s.Twin);
                Tmax = max(Tmax, s.Twin);
            end
            if Tmax <= Tmin
                Tmax = Tmin + eps;
            end
            app.UIAxes.CLim = [Tmin Tmax];
            colorbar(app.UIAxes);
            app.Plots.Quiver = gobjects(0);

            cla(app.AxesE)
            hold(app.AxesE, 'on')
            app.Plots.Energy = plot(app.AxesE, NaN, NaN, 'b-', 'LineWidth', 1.5);
            app.Plots.EnergyNow = plot(app.AxesE, NaN, NaN, 'ro', 'MarkerFaceColor', 'r');
            hold(app.AxesE, 'off')
            app.AxesE.XLim = [0 s.tend];
        end

        function drawFrame(app, f)
            % Show a time step: temperature, velocity and mean temperature
            s = app.Setup;
            app.TempoLabel.Text = sprintf('Tempo: %.4f s', f.t);

            % Temperature
            if max(f.T(:)) > min(f.T(:))
                contourf(app.UIAxes, s.xb, s.yb, f.T', 20, 'LineColor', 'none');
            else
                % Constant field (e.g. t = 0): fill the domain with its colour
                cla(app.UIAxes)
                patch(app.UIAxes, [s.xL s.xR s.xR s.xL], [s.yL s.yL s.yR s.yR], f.T(1), ...
                    'EdgeColor', 'none');
            end
            % Heated walls in red: floor and radiator
            if ~strcmp(s.Tprofile, 'Adiabática')
                line(app.UIAxes, [s.xL s.xR], [s.yL s.yL], 'Color', [0.85 0 0], ...
                    'LineWidth', 5, 'Clipping', 'off');
            end
            if s.radOn
                line(app.UIAxes, [s.xL s.xL], [s.yL s.radTop], 'Color', [0.85 0 0], ...
                    'LineWidth', 5, 'Clipping', 'off');
            end
            % Window in light blue
            if s.winOn && ~isempty(s.winBot)
                line(app.UIAxes, [s.xL s.xL], [s.winBot s.winTop], 'Color', [0.3 0.75 0.93], ...
                    'LineWidth', 5, 'Clipping', 'off');
            end

            % Velocity
            ax = app.UIAxes_2;
            switch app.VelocidadeDropDown.Value
                case 'Frechas'
                    if isempty(app.Plots.Quiver) || ~isvalid(app.Plots.Quiver)
                        cla(ax)
                        app.Plots.Quiver = quiver(ax, s.xb, s.yb, f.ub', f.vb');
                    else
                        set(app.Plots.Quiver, 'UData', f.ub', 'VData', f.vb')
                    end
                case 'Liñas de corrente'
                    cla(ax)
                    app.Plots.Quiver = gobjects(0);
                    [X, Y] = meshgrid(s.xb, s.yb);
                    if any(f.ub(:)) || any(f.vb(:))
                        h = streamslice(ax, X, Y, f.ub', f.vb', 1.5);
                        set(h, 'Color', [0 0.447 0.741])
                    end
                otherwise % 'Módulo da velocidade'
                    cla(ax)
                    app.Plots.Quiver = gobjects(0);
                    speed = sqrt(f.ub'.^2 + f.vb'.^2);
                    if max(speed(:)) > min(speed(:))
                        contourf(ax, s.xb, s.yb, speed, 20, 'LineColor', 'none');
                    end
                    colormap(ax, parula)
                    colorbar(ax);
            end
            if ~strcmp(app.VelocidadeDropDown.Value, 'Módulo da velocidade')
                colorbar(ax, 'off');
            end

            % Mean temperature
            k = find(app.Et <= f.t + 1e-12, 1, 'last');
            set(app.Plots.Energy, 'XData', app.Et, 'YData', app.Tm)
            set(app.Plots.EnergyNow, 'XData', app.Et(k), 'YData', app.Tm(k))
        end

        function d = frameDelay(app)
            % Pause between frames from the animation speed slider (1 slow, 10 fast)
            d = 0.3*(10 - app.VelocidadeAnimacionSlider.Value)/9;
        end

        function setRunning(app, running)
            % Enable or disable the controls while a simulation runs
            inputs = [app.TemperaturafronteirainferiorEditField app.PerfildetemperaturaDropDown ...
                app.VelocidadefronteirasuperiorEditField app.ViscosidadeEditField ...
                app.CoefexpansintrmicaEditField app.CoefconduccincalorEditField ...
                app.GravidadeEditField app.DominioxEditField app.yEditField ...
                app.NumeroelementosNxEditField app.NyEditField app.TempofinalEditField app.CFLSlider ...
                app.RadiadorCheckBox app.TemperaturaradiadorEditField ...
                app.FiestraCheckBox app.TemperaturafiestraEditField];
            set([inputs app.TemperaturainicialEditField], 'Enable', ~running)
            app.RunButton.Enable = ~running;
            app.PausaButton.Enable = running;
            app.DeterButton.Enable = running;
            app.PausaButton.Text = 'Pausa';
            app.TempoSlider.Enable = ~running && app.NStored > 1;
            if ~running
                app.updateBCEnable();
            end
        end

        function updateBCEnable(app)
            % Temperature fields only active when their boundary condition is used
            app.TemperaturafronteirainferiorEditField.Enable = ...
                ~strcmp(app.PerfildetemperaturaDropDown.Value, 'Adiabática');
            app.TemperaturaradiadorEditField.Enable = app.RadiadorCheckBox.Value;
            app.TemperaturafiestraEditField.Enable = app.FiestraCheckBox.Value;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Make sure the numerical code next to the app is used
            appFolder = fileparts(which(class(app)));
            if ~isempty(appFolder)
                addpath(appFolder);
            end
            app.setRunning(false);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if app.IsRunning
                return
            end
            xR = app.DominioxEditField.Value;
            yR = app.yEditField.Value;
            Nx = app.NumeroelementosNxEditField.Value;
            Ny = app.NyEditField.Value;
            %
            tend = app.TempofinalEditField.Value;
            CFL = app.CFLSlider.Value;
            %
            uL = app.VelocidadefronteirasuperiorEditField.Value;
            Tb = app.TemperaturafronteirainferiorEditField.Value;
            Tprof = app.PerfildetemperaturaDropDown.Value;
            radOn = app.RadiadorCheckBox.Value;
            Trad = app.TemperaturaradiadorEditField.Value;
            winOn = app.FiestraCheckBox.Value;
            T0 = app.TemperaturainicialEditField.Value;
            Twin = app.TemperaturafiestraEditField.Value;
            %
            gravity = app.GravidadeEditField.Value;
            mu = app.ViscosidadeEditField.Value;
            kpp = app.CoefconduccincalorEditField.Value;
            bt = app.CoefexpansintrmicaEditField.Value;
            %
            if CFL <= 0
                uialert(app.UIFigure, 'O número CFL ten que ser maior que 0.', 'CFL non válido');
                return
            end

            app.IsRunning = true;
            app.IsPaused = false;
            app.StopRequested = false;
            app.NStored = 0;
            app.setRunning(true);
            app.EstadoLabel.Text = 'Calculando...';
            drawnow

            try
                res = NavierStokes_SIMPLE_app(xR,yR,Nx,Ny,tend,CFL,uL,Tb,Tprof,gravity,mu,kpp,bt,radOn,Trad,winOn,Twin,T0,app);
            catch err
                if isvalid(app)
                    app.IsRunning = false;
                    app.IsPaused = false;
                    app.setRunning(false);
                    app.EstadoLabel.Text = 'A simulación fallou.';
                    uialert(app.UIFigure, ['Non se puido completar a simulación: ' err.message], 'Erro');
                end
                return
            end
            if ~isvalid(app)
                return
            end
            app.storeFrame(app.Last);
            app.IsRunning = false;
            app.IsPaused = false;
            app.setRunning(false);
            if res.completed
                app.EstadoLabel.Text = 'Simulación completada. Move o control de tempo para ver calquera instante.';
            else
                app.EstadoLabel.Text = sprintf('Simulación detida en t = %.4f s.', res.time);
            end
        end

        % Button pushed function: PausaButton
        function PausaButtonPushed(app, event)
            if ~app.IsRunning
                return
            end
            app.IsPaused = ~app.IsPaused;
            if app.IsPaused
                app.PausaButton.Text = 'Continuar';
                app.TempoSlider.Enable = app.NStored > 1;
                app.EstadoLabel.Text = 'En pausa. Podes mover o control de tempo para ver instantes anteriores.';
            else
                app.PausaButton.Text = 'Pausa';
                app.TempoSlider.Enable = false;
                app.EstadoLabel.Text = 'Calculando...';
            end
        end

        % Button pushed function: DeterButton
        function DeterButtonPushed(app, event)
            app.StopRequested = true;
            app.IsPaused = false;
        end

        % Button pushed function: PecharButton
        function PecharButtonPushed(app, event)
            app.StopRequested = true;
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.StopRequested = true;
            delete(app)
        end

        % Value changed function: VelocidadeDropDown
        function VelocidadeDropDownValueChanged(app, event)
            app.Plots.Quiver = gobjects(0);
            if ~isempty(app.Last)
                if app.IsRunning && ~app.IsPaused
                    app.drawFrame(app.Last);
                else
                    [~, k] = min(abs(app.Hist.t(1:app.NStored) - app.TempoSlider.Value));
                    app.drawFrame(app.storedFrame(k));
                end
            end
        end

        % Value changing function: TempoSlider
        function TempoSliderValueChanging(app, event)
            if app.NStored > 0
                [~, k] = min(abs(app.Hist.t(1:app.NStored) - event.Value));
                app.drawFrame(app.storedFrame(k));
            end
        end

        % Value changed function: PerfildetemperaturaDropDown
        function PerfildetemperaturaDropDownValueChanged(app, event)
            app.updateBCEnable();
        end

        % Value changed function: RadiadorCheckBox
        function RadiadorCheckBoxValueChanged(app, event)
            app.updateBCEnable();
        end

        % Value changed function: FiestraCheckBox
        function FiestraCheckBoxValueChanged(app, event)
            app.updateBCEnable();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Heated lid-driven cavity';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.RightPanel = uipanel(app.GridLayout);
            app.RightGrid = uigridlayout(app.RightPanel);
            app.AxesE = uiaxes(app.RightGrid);
            title(app.AxesE, 'Temperatura media no dominio')
            xlabel(app.AxesE, 't')
            ylabel(app.AxesE, 'T media')
            app.UIAxes_2 = uiaxes(app.RightGrid);
            title(app.UIAxes_2, 'Velocidade')
            xlabel(app.UIAxes_2, 'x')
            ylabel(app.UIAxes_2, 'y')
            app.UIAxes = uiaxes(app.RightGrid);
            title(app.UIAxes, 'Temperatura')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'y')
            app.TempoGrid = uigridlayout(app.RightGrid);
            app.TempoSlider = uislider(app.TempoGrid);
            app.TempoSlider.Limits = [0 1];
            app.TempoSlider.ValueChangingFcn = createCallbackFcn(app, @TempoSliderValueChanging, true);
            app.TempoSlider.Enable = 'off';
            app.TempoSlider.Tooltip = 'Despois de executar (ou en pausa), move este control para ver calquera instante';
            app.TempoSliderLabel = uilabel(app.TempoGrid);
            app.TempoSliderLabel.Text = 'Tempo';
            app.TempoLabel = uilabel(app.RightGrid);
            app.TempoLabel.Text = 'Tempo: 0.0000 s';
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftGrid = uigridlayout(app.LeftPanel);
            app.EstadoLabel = uilabel(app.LeftGrid);
            app.EstadoLabel.Text = 'Introduce os parámetros e preme Executar.';
            app.Botons2Grid = uigridlayout(app.LeftGrid);
            app.PecharButton = uibutton(app.Botons2Grid, 'push');
            app.PecharButton.ButtonPushedFcn = createCallbackFcn(app, @PecharButtonPushed, true);
            app.PecharButton.Text = 'Pechar';
            app.DeterButton = uibutton(app.Botons2Grid, 'push');
            app.DeterButton.ButtonPushedFcn = createCallbackFcn(app, @DeterButtonPushed, true);
            app.DeterButton.Enable = 'off';
            app.DeterButton.Text = 'Deter';
            app.Botons1Grid = uigridlayout(app.LeftGrid);
            app.PausaButton = uibutton(app.Botons1Grid, 'push');
            app.PausaButton.ButtonPushedFcn = createCallbackFcn(app, @PausaButtonPushed, true);
            app.PausaButton.Enable = 'off';
            app.PausaButton.Text = 'Pausa';
            app.RunButton = uibutton(app.Botons1Grid, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Executar';
            app.VisualizacionPanel = uipanel(app.LeftGrid);
            app.VisualizacionPanel.Title = 'Visualización';
            app.VisualizacionGrid = uigridlayout(app.VisualizacionPanel);
            app.VelocidadeAnimacionSlider = uislider(app.VisualizacionGrid);
            app.VelocidadeAnimacionSlider.Limits = [1 10];
            app.VelocidadeAnimacionSlider.MajorTicks = [1 10];
            app.VelocidadeAnimacionSlider.MajorTickLabels = {'lenta', 'rápida'};
            app.VelocidadeAnimacionSlider.Tooltip = 'Velocidade da animación';
            app.VelocidadeAnimacionSlider.Value = 8;
            app.VelocidadeAnimacionLabel = uilabel(app.VisualizacionGrid);
            app.VelocidadeAnimacionLabel.Text = 'Velocidade da animación';
            app.VelocidadeDropDown = uidropdown(app.VisualizacionGrid);
            app.VelocidadeDropDown.Items = {'Frechas', 'Liñas de corrente', 'Módulo da velocidade'};
            app.VelocidadeDropDown.ValueChangedFcn = createCallbackFcn(app, @VelocidadeDropDownValueChanged, true);
            app.VelocidadeDropDown.Tooltip = 'Como se debuxa o campo de velocidades (pódese cambiar en calquera momento)';
            app.VelocidadeDropDown.Value = 'Frechas';
            app.VelocidadeDropDownLabel = uilabel(app.VisualizacionGrid);
            app.VelocidadeDropDownLabel.Text = 'Velocidade';
            app.DiscretizaciontemporalPanel = uipanel(app.LeftGrid);
            app.DiscretizaciontemporalPanel.Title = 'Discretización temporal';
            app.TemporalGrid = uigridlayout(app.DiscretizaciontemporalPanel);
            app.CFLSlider = uislider(app.TemporalGrid);
            app.CFLSlider.Limits = [0 2];
            app.CFLSlider.MajorTicks = [0 0.5 1 1.5 2];
            app.CFLSlider.Tooltip = 'Número CFL: controla o paso de tempo';
            app.CFLSlider.Value = 1;
            app.CFLSliderLabel = uilabel(app.TemporalGrid);
            app.CFLSliderLabel.Text = 'CFL';
            app.TempofinalEditField = uieditfield(app.TemporalGrid, 'numeric');
            app.TempofinalEditField.Limits = [0 Inf];
            app.TempofinalEditField.Tooltip = 'Tempo final da simulación';
            app.TempofinalEditField.Value = 1;
            app.TempofinalLabel = uilabel(app.TemporalGrid);
            app.TempofinalLabel.Text = 'Tempo final';
            app.DiscretizacionespacialPanel = uipanel(app.LeftGrid);
            app.DiscretizacionespacialPanel.Title = 'Discretización espacial';
            app.EspacialGrid = uigridlayout(app.DiscretizacionespacialPanel);
            app.NyEditField = uieditfield(app.EspacialGrid, 'numeric');
            app.NyEditField.Limits = [2 Inf];
            app.NyEditField.RoundFractionalValues = 'on';
            app.NyEditField.Tooltip = 'Número de celas en y';
            app.NyEditField.Value = 25;
            app.NyEditFieldLabel = uilabel(app.EspacialGrid);
            app.NyEditFieldLabel.Text = 'Ny';
            app.NumeroelementosNxEditField = uieditfield(app.EspacialGrid, 'numeric');
            app.NumeroelementosNxEditField.Limits = [2 Inf];
            app.NumeroelementosNxEditField.RoundFractionalValues = 'on';
            app.NumeroelementosNxEditField.Tooltip = 'Número de celas en x';
            app.NumeroelementosNxEditField.Value = 25;
            app.NumeroelementosNxEditFieldLabel = uilabel(app.EspacialGrid);
            app.NumeroelementosNxEditFieldLabel.Text = 'Número elementos:   Nx';
            app.yEditField = uieditfield(app.EspacialGrid, 'numeric');
            app.yEditField.LowerLimitInclusive = 'off';
            app.yEditField.Limits = [0 Inf];
            app.yEditField.Tooltip = 'Altura da cavidade';
            app.yEditField.Value = 1;
            app.yEditFieldLabel = uilabel(app.EspacialGrid);
            app.yEditFieldLabel.Text = 'y';
            app.DominioxEditField = uieditfield(app.EspacialGrid, 'numeric');
            app.DominioxEditField.LowerLimitInclusive = 'off';
            app.DominioxEditField.Limits = [0 Inf];
            app.DominioxEditField.Tooltip = 'Anchura da cavidade';
            app.DominioxEditField.Value = 1;
            app.DominioxLabel = uilabel(app.EspacialGrid);
            app.DominioxLabel.Text = 'Dominio:   x';
            app.PropiedadesdofluidoPanel = uipanel(app.LeftGrid);
            app.PropiedadesdofluidoPanel.Title = 'Propiedades do fluído';
            app.FluidoGrid = uigridlayout(app.PropiedadesdofluidoPanel);
            app.GravidadeEditField = uieditfield(app.FluidoGrid, 'numeric');
            app.GravidadeEditField.Limits = [0 Inf];
            app.GravidadeEditField.Tooltip = 'Aceleración da gravidade';
            app.GravidadeEditField.Value = 9.81;
            app.GravidadeEditFieldLabel = uilabel(app.FluidoGrid);
            app.GravidadeEditFieldLabel.Text = 'Gravidade';
            app.CoefconduccincalorEditField = uieditfield(app.FluidoGrid, 'numeric');
            app.CoefconduccincalorEditField.Limits = [0 Inf];
            app.CoefconduccincalorEditField.Tooltip = 'Rapidez coa que o calor se difunde no fluído';
            app.CoefconduccincalorEditField.Value = 0.01;
            app.CoefconduccincalorEditFieldLabel = uilabel(app.FluidoGrid);
            app.CoefconduccincalorEditFieldLabel.Text = 'Coef. condución calor';
            app.CoefexpansintrmicaEditField = uieditfield(app.FluidoGrid, 'numeric');
            app.CoefexpansintrmicaEditField.Limits = [0 Inf];
            app.CoefexpansintrmicaEditField.Tooltip = 'Canto se expande o fluído ao quentarse (empuxe de flotación)';
            app.CoefexpansintrmicaEditField.Value = 1;
            app.CoefexpansintrmicaEditFieldLabel = uilabel(app.FluidoGrid);
            app.CoefexpansintrmicaEditFieldLabel.Text = 'Coef. expansión térmica';
            app.ViscosidadeEditField = uieditfield(app.FluidoGrid, 'numeric');
            app.ViscosidadeEditField.Limits = [0 Inf];
            app.ViscosidadeEditField.Tooltip = 'Viscosidade cinemática do fluído';
            app.ViscosidadeEditField.Value = 0.01;
            app.ViscosidadeEditFieldLabel = uilabel(app.FluidoGrid);
            app.ViscosidadeEditFieldLabel.Text = 'Viscosidade';
            app.ContornoPanel = uipanel(app.LeftGrid);
            app.ContornoPanel.Title = 'Condición inicial e de contorno';
            app.ContornoGrid = uigridlayout(app.ContornoPanel);
            app.TemperaturafiestraEditField = uieditfield(app.ContornoGrid, 'numeric');
            app.TemperaturafiestraEditField.Enable = 'off';
            app.TemperaturafiestraEditField.Tooltip = 'Temperatura imposta na fiestra (pode ser negativa: inverno)';
            app.TemperaturafiestraEditFieldLabel = uilabel(app.ContornoGrid);
            app.TemperaturafiestraEditFieldLabel.Text = 'Temperatura da fiestra';
            app.FiestraCheckBox = uicheckbox(app.ContornoGrid);
            app.FiestraCheckBox.ValueChangedFcn = createCallbackFcn(app, @FiestraCheckBoxValueChanged, true);
            app.FiestraCheckBox.Tooltip = 'Fiestra idealizada: temperatura fixa no terzo central da parede esquerda, xusto enriba do radiador';
            app.FiestraCheckBox.Text = 'Fiestra (2º terzo da parede esquerda)';
            app.TemperaturaradiadorEditField = uieditfield(app.ContornoGrid, 'numeric');
            app.TemperaturaradiadorEditField.Limits = [0 Inf];
            app.TemperaturaradiadorEditField.Enable = 'off';
            app.TemperaturaradiadorEditField.Tooltip = 'Temperatura imposta no radiador';
            app.TemperaturaradiadorEditField.Value = 30;
            app.TemperaturaradiadorEditFieldLabel = uilabel(app.ContornoGrid);
            app.TemperaturaradiadorEditFieldLabel.Text = 'Temperatura do radiador';
            app.RadiadorCheckBox = uicheckbox(app.ContornoGrid);
            app.RadiadorCheckBox.ValueChangedFcn = createCallbackFcn(app, @RadiadorCheckBoxValueChanged, true);
            app.RadiadorCheckBox.Tooltip = 'Radiador idealizado: temperatura fixa no terzo inferior da parede esquerda';
            app.RadiadorCheckBox.Text = 'Radiador (1/3 inferior da parede esquerda)';
            app.VelocidadefronteirasuperiorEditField = uieditfield(app.ContornoGrid, 'numeric');
            app.VelocidadefronteirasuperiorEditField.Tooltip = 'Velocidade horizontal da tapa (parede superior)';
            app.VelocidadefronteirasuperiorEditFieldLabel = uilabel(app.ContornoGrid);
            app.VelocidadefronteirasuperiorEditFieldLabel.Text = 'Velocidade fronteira superior';
            app.PerfildetemperaturaDropDown = uidropdown(app.ContornoGrid);
            app.PerfildetemperaturaDropDown.Items = {'Lineal', 'Gaussiana', 'Adiabática'};
            app.PerfildetemperaturaDropDown.ValueChangedFcn = createCallbackFcn(app, @PerfildetemperaturaDropDownValueChanged, true);
            app.PerfildetemperaturaDropDown.Tooltip = 'Lineal: temperatura constante en toda a parede inferior. Gaussiana: só quenta o centro. Adiabática: o chan non intercambia calor';
            app.PerfildetemperaturaDropDown.Value = 'Lineal';
            app.PerfildetemperaturaDropDownLabel = uilabel(app.ContornoGrid);
            app.PerfildetemperaturaDropDownLabel.Text = 'Perfil de temperatura';
            app.TemperaturafronteirainferiorEditField = uieditfield(app.ContornoGrid, 'numeric');
            app.TemperaturafronteirainferiorEditField.Limits = [0 Inf];
            app.TemperaturafronteirainferiorEditField.Tooltip = 'Temperatura imposta na parede inferior da cavidade';
            app.TemperaturafronteirainferiorEditField.Value = 30;
            app.TemperaturafronteirainferiorEditFieldLabel = uilabel(app.ContornoGrid);
            app.TemperaturafronteirainferiorEditFieldLabel.Text = 'Temperatura fronteira inferior';
            app.TemperaturainicialEditField = uieditfield(app.ContornoGrid, 'numeric');
            app.TemperaturainicialEditField.Tooltip = 'Temperatura do fluído en todo o dominio no instante inicial';
            app.TemperaturainicialEditField.Value = 5;
            app.TemperaturainicialEditFieldLabel = uilabel(app.ContornoGrid);
            app.TemperaturainicialEditFieldLabel.Text = 'Temperatura inicial';
            app.HeatedliddrivencavityLabel = uilabel(app.LeftGrid);
            app.HeatedliddrivencavityLabel.Text = 'Heated lid-driven cavity';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CNS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end